classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ResetButton        matlab.ui.control.Button
        TiltSheerButton    matlab.ui.control.Button
        ResoltrLabel       matlab.ui.control.Label
        ResolnormLabel     matlab.ui.control.Label
        SCALEButton        matlab.ui.control.Button
        TranslationButton  matlab.ui.control.Button
        RotateButton       matlab.ui.control.Button
        SaveDatasetButton  matlab.ui.control.Button
        LoadImageButton    matlab.ui.control.Button
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end


     properties (Access = private)
    image % Stores the loaded image
    dataset % Stores the transformed images
    transformedImage  % Stores the current transformed image
    interpolation_rot 
    interpolation_scale
    rotation
    shiftX
    shiftY
    scaleX
    scaleY
    tiltsheer_angle
    shear_matrix
    transformOrder % Stores the order of transformations
end

methods (Access = public)

    function updateResolutionLabels(app)
            % Get the size of the image
            normalRes = size(app.image);
            transfRes = size(app.transformedImage);

            % Update labels
            app.ResolnormLabel.Text = sprintf('Normal im. resol.: %d x %d', normalRes(1), normalRes(2));
            app.ResoltrLabel.Text = sprintf('Transf. im. resol.: %d x %d', transfRes(1), transfRes(2));
        end

        function apply_all(app)
            app.transformedImage = app.image;

            % Apply transformations based on transformOrder
            for i = 1:numel(app.transformOrder)
                transform = app.transformOrder{i};
                switch transform.type
                    case 'rotation'
                        app.transformedImage = imrotate(app.transformedImage, transform.angle, transform.interpolation, 'crop');
                    case 'translation'
                        app.transformedImage = imtranslate(app.transformedImage, [transform.x, transform.y]);
                    case 'scale'
                         app.transformedImage = imresize(app.transformedImage, [round(size(app.transformedImage, 1) * transform.scaleY), round(size(app.transformedImage, 2) * transform.scaleX)], transform.interpolation, "Antialiasing", true);
                    case 'tilt/sheer'
                        app.transformedImage = imwarp(app.transformedImage, transform.tform, 'FillValues', 255);
                end
            end

            app.dataset{end+1} = app.transformedImage;
            updateResolutionLabels(app);
            imshow(app.transformedImage, 'Parent', app.UIAxes_2);
        end


        function applyRotation(app, angle, interpMethod)
            app.interpolation_scale='';
            app.shiftX=0;
            app.shiftY=0;
            app.scaleX=0;
            app.scaleY=0;
            app.tiltsheer_angle=0;
            app.shear_matrix=eye(3);

            % Apply rotation to the current transformed image
            app.interpolation_rot = interpMethod;
            app.rotation = app.rotation + angle;

            % Update transformOrder
            rotationIndices = find(cellfun(@(x) strcmp(x.type, 'rotation'), app.transformOrder));
            if isempty(rotationIndices)
                app.transformOrder{end+1} = struct('type', 'rotation', 'angle', app.rotation,'interpolation',app.interpolation_rot);
            elseif app.transformOrder{end}.type=="rotation"
                app.transformOrder{end} = struct('type', 'rotation', 'angle', app.rotation,'interpolation',app.interpolation_rot);
            else
                app.transformOrder{end+1} = struct('type', 'rotation', 'angle', app.rotation,'interpolation',app.interpolation_rot);
            end
        end

        function applyTranslation(app, xShift, yShift)

            app.rotation=0;
            app.scaleX=0;
            app.scaleY=0;
            app.tiltsheer_angle=0;
            app.shear_matrix=eye(3);

            % Apply translation and display result
            app.shiftX = app.shiftX + xShift;
            app.shiftY = app.shiftY + yShift;

            % Update transformOrder
            translationIndices = find(cellfun(@(x) strcmp(x.type, 'translation'), app.transformOrder));
            if isempty(translationIndices)
            app.transformOrder{end+1} = struct('type', 'translation', 'x', app.shiftX, 'y', app.shiftY);
            elseif app.transformOrder{end}.type=="translation"
                app.transformOrder{end} = struct('type', 'translation', 'x', app.shiftX, 'y', app.shiftY);
            else
             app.transformOrder{end+1} = struct('type', 'translation', 'x', app.shiftX, 'y', app.shiftY);
            end
        end

        function applyScale(app, scaleFactorX, scaleFactorY, interpMethod)
            app.rotation=0;
            app.shiftX=0;
            app.shiftY=0;
            app.tiltsheer_angle=0;
            app.shear_matrix=eye(3);

            % Apply scaling to the current transformed image
            app.scaleX = scaleFactorX;
            app.scaleY = scaleFactorY;
            app.interpolation_scale = interpMethod;
            
            % Update transformOrder
            scaleIndices = find(cellfun(@(x) strcmp(x.type, 'scale'), app.transformOrder));
            if isempty(scaleIndices)
                app.transformOrder{end+1} = struct('type', 'scale', 'scaleX', app.scaleX, 'scaleY', app.scaleY, 'interpolation',app.interpolation_scale);
            elseif app.transformOrder{end}.type=="scale"
                app.transformOrder{end} = struct('type', 'scale', 'scaleX', app.scaleX, 'scaleY', app.scaleY,'interpolation',app.interpolation_scale);
            else
                app.transformOrder{end+1} = struct('type', 'scale', 'scaleX', app.scaleX, 'scaleY', app.scaleY,'interpolation',app.interpolation_scale);
            end
        end

        function applyShearTilt(app, tiltAmount, tiltAxis, transformType)
            app.rotation=0;
            app.shiftX=0;
            app.shiftY=0;
            app.scaleX=0;
            app.scaleY=0;

            % Add tilt to transformOrder
            app.tiltsheer_angle=app.tiltsheer_angle+tiltAmount;

            sheerAngle = deg2rad(app.tiltsheer_angle);
            if tiltAxis == 'x'
                app.shear_matrix(2, 1) = tan(sheerAngle);
            elseif tiltAxis == 'y'
                app.shear_matrix(1, 2) = tan(sheerAngle);
            end
            app.shear_matrix

            if strcmpi(transformType, 'tilt')
                app.shear_matrix(2, 1) = tan(sheerAngle);
                app.shear_matrix(1, 2) = tan(sheerAngle);
                tform = projective2d(app.shear_matrix);

            elseif strcmpi(transformType, 'shear')
                tform = affine2d(app.shear_matrix);
                tform.T
                
            end

            tiltIndices = find(cellfun(@(x) strcmp(x.type, 'tilt/sheer'), app.transformOrder));
            if isempty(tiltIndices)
                app.transformOrder{end+1} = struct('type', 'tilt/sheer', 'tiltAmount', tiltAmount, 'tiltAxis', tiltAxis, 'transformType', transformType,'tform',tform.T);
            elseif app.transformOrder{end}.type=="tilt/sheer"
                app.transformOrder{end} = struct('type', 'tilt/sheer', 'tiltAmount', tiltAmount, 'tiltAxis', tiltAxis, 'transformType', transformType,'tform',tform.T);
            else
                app.transformOrder{end+1} = struct('type', 'tilt/sheer', 'tiltAmount', tiltAmount, 'tiltAxis', tiltAxis, 'transformType', transformType,'tform',tform.T);
            end
        end


end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Set default image if no image is loaded
            if isempty(app.image)
                app.image = imread('images\default.jpg'); % Replace 'default_image.jpg' with your default image path
                imshow(app.image, 'Parent', app.UIAxes);
                imshow(app.image, 'Parent', app.UIAxes_2);

                % Update resolution labels
                updateResolutionLabels(app);
            end
            app.rotation=0;
            app.shiftX=0;
            app.shiftY=0;
            app.scaleX=0;
            app.scaleY=0;
            app.interpolation_rot='';
            app.interpolation_scale='';
            app.transformOrder = {};
            app.tiltsheer_angle=0;
             app.shear_matrix=eye(3);
            % Set axes visibility and labels off
            set(app.UIAxes, 'Visible', 'off');
            set(app.UIAxes_2, 'Visible', 'off');
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
            xlabel(app.UIAxes_2, '');
            ylabel(app.UIAxes_2, '');
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.tif;*.jpg;*.png;*.bmp', ...
                'Image Files (*.tif; *.jpg, *.png, *.bmp)'}, 'Select an image');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            %update the cumulative values to 0 
            app.rotation=0;
            app.shiftX=0;
            app.shiftY=0;
            app.scaleX=0;
            app.scaleY=0;
            app.interpolation_rot='';
            app.interpolation_scale='';
            app.transformOrder = {};
            app.tiltsheer_angle=0;
            app.shear_matrix=eye(3);
            app.image = imread(fullfile(pathname, filename));
            app.transformedImage = app.image;

            %Update transformed image to the original uploaded image
            imshow(app.image, 'Parent', app.UIAxes);
            imshow(app.transformedImage, 'Parent', app.UIAxes_2);

            % Update resolution labels
            updateResolutionLabels(app);
            set(app.UIAxes, 'Visible', 'off');
            set(app.UIAxes_2, 'Visible', 'off');
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            % Create dialog box
            d = uifigure('Name', 'Rotate Image', 'Position', [500 500 500 200]);

            % Add rotation angle input field
            rotationLabel = uilabel(d, 'Text', 'Enter rotation angle:', 'Position', [20 170 150 20]);
            rotationSlider = uislider(d, 'Position', [170 180 250 3], 'Limits', [-90 90], 'Value', 0);

            % Add radiobuttons for interpolation methods
            interpolationTypeLabel = uilabel(d, 'Text', 'Interpolation Type:', 'Position', [20 120 100 20]);
            interpolationButtonGroup = uibuttongroup(d, 'Position', [120 80 300 60]);
            nearestRadioButton = uiradiobutton(interpolationButtonGroup, 'Text', 'Nearest', 'Position', [10 30 80 20]);
            bilinearRadioButton = uiradiobutton(interpolationButtonGroup, 'Text', 'Bilinear', 'Position', [90 30 80 20]);
            bicubicRadioButton = uiradiobutton(interpolationButtonGroup, 'Text', 'Bicubic', 'Position', [170 30 80 20]);

            % Add a button to apply rotation
            applyButton = uibutton(d, 'push', 'Text', 'Apply', 'Position', [110 20 70 30]);

            applyButton.ButtonPushedFcn = @(src, event) rotationButtonCallback();
            function rotationButtonCallback()
                applyRotation(app, rotationSlider.Value, getInterpolationType());
                apply_all(app);
            end
            function interpMethod = getInterpolationType()
                % Determine the selected interpolation type from the radiobuttons
                if nearestRadioButton.Value
                    interpMethod = 'nearest';
                elseif bilinearRadioButton.Value
                    interpMethod = 'bilinear';
                elseif bicubicRadioButton.Value
                    interpMethod = 'bicubic';
                else
                    interpMethod = 'nearest'; % Default
                end
            end

        end

        % Button pushed function: SaveDatasetButton
        function SaveDatasetButtonPushed(app, event)

            % Check if the dataset is empty
            if isempty(app.dataset)
                disp('Dataset is empty. No images to save.')
                return;
            end

            % Prompt the user for the file name and location to save the images
            [filename, pathname] = uiputfile({'*.tif;*.jpg;*.png;*.bmp', ...
                'Image Files (*.tif; *.jpg, *.png, *.bmp)'}, 'Save Dataset');

            % Check if the user canceled the operation
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end

            % Create a subfolder named "images" if it doesn't exist
            imageFolder = fullfile(pathname, 'images');
            if ~exist(imageFolder, 'dir')
                mkdir(imageFolder);
            end

            % Save each image in the dataset

            % Generate a unique file name for each image
            [~, name, ext] = fileparts(filename);
            uniqueFilename = sprintf('%s_%d%s', name, numel(app.dataset), ext);

            % Full file path to save the image
            fullFilePath = fullfile(imageFolder, uniqueFilename);

            % Save the image using imwrite
            imwrite(app.dataset{numel(app.dataset)}, fullFilePath);

            disp('Dataset saved successfully.')
        end

        % Button pushed function: TranslationButton
        function TranslationButtonPushed(app, event)
            % Create dialog box
            d = uifigure('Name', 'Translate Image', 'Position', [500 500 300 250]);

            % Add translation distance input fields
            xTranslationInput = uieditfield(d, 'numeric', 'Position', [20 140 70 20]);
            yTranslationInput = uieditfield(d, 'numeric', 'Position', [20 100 70 20]);

            % Add sliders for translation distance
            xTranslationLabel = uilabel(d, 'Text', 'Shift along X-axis:', 'Position', [20 160 150 20]);
            xTranslationSlider = uislider(d, 'Position', [170 170 100 3], 'Limits', [-50 50], 'Value', 0);
            xTranslationSlider.ValueChangedFcn = @(src, event) updateEditField(xTranslationInput, src.Value);

            yTranslationLabel = uilabel(d, 'Text', 'Shift along Y-axis:', 'Position', [20 120 150 20]);
            yTranslationSlider = uislider(d, 'Position', [170 130 100 3], 'Limits', [-50 50], 'Value', 0);
            yTranslationSlider.ValueChangedFcn = @(src, event) updateEditField(yTranslationInput, src.Value);

            % Add a button to apply translation
            applyButton = uibutton(d, 'push', 'Text', 'Apply', 'Position', [110 30 70 30]);
            applyButton.ButtonPushedFcn = @(src, event) translationButtonCallback();

            function translationButtonCallback()
                applyTranslation(app, xTranslationSlider.Value, yTranslationSlider.Value);
                apply_all(app);
            end


            function updateEditField(editField, sliderValue)
                % Update the value in the edit field when the slider is moved
                editField.Value = sliderValue;
            end


        end

        % Button pushed function: SCALEButton
        function SCALEButtonPushed(app, event)
            % Create dialog box
            d = uifigure('Name', 'Scale Image', 'Position', [500 500 380 380]);

            % Add Scaling Method radiobuttons
            scalingMethodLabel = uilabel(d, 'Text', 'Scaling Method:', 'Position', [20 340 110 20]);
            scalingButtonGroup = uibuttongroup(d, 'Position', [130 320 240 40]);
            allAxesRadioButton = uiradiobutton(scalingButtonGroup, 'Text', 'Scale All Axes', 'Position', [10 15 100 20]);
            individualAxesRadioButton = uiradiobutton(scalingButtonGroup, 'Text', 'Scale Individual Axes (X or Y)', 'Position', [120 15 140 20]);

            % Add scale factor sliders
            scaleLabel = uilabel(d, 'Text', 'Scale Factor X:', 'Position', [20 280 100 20]);
            scaleSliderX = uislider(d, 'Position', [120 290 200 3], 'Limits', [0.1 2], 'Value', 1);
            scaleLabelY = uilabel(d, 'Text', 'Scale Factor Y:', 'Position', [20 240 100 20], 'Visible', 'off');
            scaleSliderY = uislider(d, 'Position', [120 250 200 3], 'Limits', [0.1 2], 'Value', 1, 'Visible', 'off');

            % Set a callback for radio buttons
            scalingButtonGroup.SelectionChangedFcn = @(btnGroup, event) updateScaleSliders(event.NewValue.Text, scaleSliderX, scaleSliderY, scaleLabelY);

            % Add interpolation type radiobuttons
            interpolationTypeLabel = uilabel(d, 'Text', 'Interpolation Type:', 'Position', [10 180 100 20]);
            interpolationButtonGroup = uibuttongroup(d, 'Position', [110 150 240 60]);

            nearestRadioButton = uiradiobutton(interpolationButtonGroup, 'Text', 'Nearest', 'Position', [10 30 80 20]);
            bilinearRadioButton = uiradiobutton(interpolationButtonGroup, 'Text', 'Bilinear', 'Position', [90 30 80 20]);
            bicubicRadioButton = uiradiobutton(interpolationButtonGroup, 'Text', 'Bicubic', 'Position', [170 30 80 20]);
            boxRadioButton = uiradiobutton(interpolationButtonGroup, 'Text', 'Box', 'Position', [10 5 80 20]);
            lanczos2RadioButton = uiradiobutton(interpolationButtonGroup, 'Text', 'Lanczos2', 'Position', [90 5 80 20]);
            lanczos3RadioButton = uiradiobutton(interpolationButtonGroup, 'Text', 'Lanczos3', 'Position', [170 5 80 20]);

            % Add resolution labels
            originalResLabel = uilabel(d, 'Text', '', 'Position', [20 60 200 20]);
            modifiedResLabel = uilabel(d, 'Text', '', 'Position', [20 30 200 20]);

            % Add an Apply button
            applyButton = uibutton(d, 'push', 'Text', 'Apply', 'Position', [150 10 80 30]);
            applyButton.ButtonPushedFcn = @(src, event) applyScaleLogic(app, scaleSliderX.Value, scaleSliderY.Value, getInterpolationType(), getScalingMethod());

            function applyScaleLogic(app, scaleFactorX, scaleFactorY, interpolationType, scalingMethod)
                % Determine how to apply scaling to the transformed image and display result
                if strcmp(scalingMethod, 'all')
                    % Here you need to modify your applyScale function to use the same scaleFactor for both X and Y
                    applyScale(app, scaleFactorX, scaleFactorX, interpolationType);
                else
                    % Here you need to modify your applyScale function to use the individual scaleFactors for X and Y
                    applyScale(app, scaleFactorX, scaleFactorY, interpolationType);
                end
                apply_all(app);
            end

            function scalingMethod = getScalingMethod()
                % Determine the selected scaling method from the radiobuttons
                if allAxesRadioButton.Value
                    scalingMethod = 'all';
                elseif individualAxesRadioButton.Value
                    scalingMethod = 'individual';
                else
                    scalingMethod = 'all'; % Default
                end
            end

            function updateScaleSliders(scalingMethodText, scaleSliderX, scaleSliderY, scaleLabelY)
                if strcmp(scalingMethodText, 'Scale All Axes')
                    scaleSliderX.Visible = 'on';
                    scaleSliderY.Visible = 'off';
                    scaleLabelY.Visible = 'off';
                else
                    scaleSliderX.Visible = 'on';
                    scaleSliderY.Visible = 'on';
                    scaleLabelY.Visible = 'on';
                end
            end

            function interpolationType = getInterpolationType()
                % Determine the selected interpolation type from the radiobuttons
                if nearestRadioButton.Value
                    interpolationType = 'nearest';
                elseif bilinearRadioButton.Value
                    interpolationType = 'bilinear';
                elseif bicubicRadioButton.Value
                    interpolationType = 'bicubic';
                elseif boxRadioButton.Value
                    interpolationType = 'box';
                elseif lanczos2RadioButton.Value
                    interpolationType = 'lanczos2';
                elseif lanczos3RadioButton.Value
                    interpolationType = 'lanczos3';
                else
                    interpolationType = 'nearest'; % Default
                end
            end
        end

        % Button pushed function: TiltSheerButton
        function TiltSheerButtonPushed(app, event)
            % Create dialog box
            d = uifigure('Name', 'Shear/Tilt Image', 'Position', [500 500 400 300]);

            % Add shear slider
            shearLabel = uilabel(d, 'Text', 'Enter shear amount:', 'Position', [20 250 150 20]);
            shearSlider = uislider(d, 'Position', [130 260 230 3], 'Limits', [-30 30], 'Value', 0);

            % Add radio buttons for shear axis
            shearAxisLabel = uilabel(d, 'Text', 'Choose shear axis:', 'Position', [20 180 150 20]);
            shearAxisButtonGroup = uibuttongroup(d, 'Position', [120 150 100 50]);
            xAxisRadio = uiradiobutton(shearAxisButtonGroup, 'Text', 'X-Axis', 'Position', [10 10 60 20]);
            yAxisRadio = uiradiobutton(shearAxisButtonGroup, 'Text', 'Y-Axis', 'Position', [10 30 60 20]);

            % Add radio buttons for transformation type
            transformTypeLabel = uilabel(d, 'Text', 'Choose transformation type:', 'Position', [20 120 160 20]);
            transformTypeButtonGroup = uibuttongroup(d, 'Position', [190 70 100 70]);
            affineRadio = uiradiobutton(transformTypeButtonGroup, 'Text', 'Shear', 'Position', [10 50 60 20]);
            projectiveRadio = uiradiobutton(transformTypeButtonGroup, 'Text', 'Tilt', 'Position', [10 30 80 20]);
            xAxisRadio.Value = true; % Set X-Axis as default
            affineRadio.Value = true; % Set Affine as default

            % Add Apply button
            applyButton = uibutton(d, 'push', 'Text', 'Apply', 'Position', [110 30 70 30]);
            applyButton.ButtonPushedFcn = @(src, event) callbackapplyShear(app);

            function shearAxis = getShearAxis()
                if xAxisRadio.Value
                    shearAxis = 'x';
                else
                    shearAxis = 'y';
                end
            end

            function transformType = getTransformType()
                if affineRadio.Value
                    transformType = 'Shear';
                else
                    transformType = 'Tilt';
                end
            end

            function callbackapplyShear(app)
                % Determine the shear matrix based on the chosen axis
                applyShearTilt(app, shearSlider.Value, getShearAxis(), getTransformType());
                apply_all(app);

                % delete(d); % Uncomment this line if you want to close the dialog after applying the shear
            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.transformedImage=app.image;
            app.rotation=0;
            app.shiftX=0;
            app.shiftY=0;
            app.scaleX=0;
            app.scaleY=0;
            app.interpolation_rot='';
            app.interpolation_scale='';
            app.transformOrder = {};
            app.tiltsheer_angle=0;
            app.shear_matrix=eye(3);
            imshow(app.transformedImage, 'Parent', app.UIAxes_2);
            updateResolutionLabels(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Normal Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Transformed image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveDatasetButton = uibutton(app.UIFigure, 'push');
            app.SaveDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDatasetButtonPushed, true);
            app.SaveDatasetButton.Text = 'Save Dataset';
            app.RotateButton = uibutton(app.UIFigure, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.TranslationButton = uibutton(app.UIFigure, 'push');
            app.TranslationButton.ButtonPushedFcn = createCallbackFcn(app, @TranslationButtonPushed, true);
            app.TranslationButton.Text = 'Translation';
            app.SCALEButton = uibutton(app.UIFigure, 'push');
            app.SCALEButton.ButtonPushedFcn = createCallbackFcn(app, @SCALEButtonPushed, true);
            app.SCALEButton.Text = 'SCALE';
            app.ResolnormLabel = uilabel(app.UIFigure);
            app.ResolnormLabel.Interpreter = 'latex';
            app.ResolnormLabel.Text = 'Resol. norm:';
            app.ResoltrLabel = uilabel(app.UIFigure);
            app.ResoltrLabel.Interpreter = 'latex';
            app.ResoltrLabel.Text = 'Resol tr:';
            app.TiltSheerButton = uibutton(app.UIFigure, 'push');
            app.TiltSheerButton.ButtonPushedFcn = createCallbackFcn(app, @TiltSheerButtonPushed, true);
            app.TiltSheerButton.Text = 'Tilt/Sheer';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end